classdef GUI_GlobalBioIm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GlobalBioImGUIUIFigure        matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Main                          matlab.ui.container.Tab
        ORLabel                       matlab.ui.control.Label
        DesignandsolveyourowninverseproblemButton  matlab.ui.control.Button
        SelectandsolveanexistinginverseproblemButton  matlab.ui.control.Button
        LOADYOURDATALabel             matlab.ui.control.Label
        BrowseButton_2                matlab.ui.control.Button
        Button_6                      matlab.ui.control.Button
        ForwardModel                  matlab.ui.container.Tab
        TextArea_ForwardModel         matlab.ui.control.TextArea
        LabelTextArea                 matlab.ui.control.Label
        Button_AddLinOp               matlab.ui.control.Button
        Button_SeeDoc                 matlab.ui.control.Button
        OperatorLabel                 matlab.ui.control.Label
        DropDown_SelectLinOp          matlab.ui.control.DropDown
        Next                          matlab.ui.control.Button
        FMPanel                       matlab.ui.container.Panel
        Button_3                      matlab.ui.control.Button
        CostFunction                  matlab.ui.container.Tab
        TextArea_CostFunction         matlab.ui.control.TextArea
        CostFunctionLabel             matlab.ui.control.Label
        Button_Add_DataFidelity       matlab.ui.control.Button
        Button_SeeDoc_DataFidelity    matlab.ui.control.Button
        DatafidelityLabel             matlab.ui.control.Label
        DropDown_Select_DataFidelity  matlab.ui.control.DropDown
        FMPanel_CostFunction          matlab.ui.container.Panel
        Button_Add_Regul              matlab.ui.control.Button
        Button_SeeDoc_Regul           matlab.ui.control.Button
        RegularizerLabel              matlab.ui.control.Label
        DropDown_Select_Regul         matlab.ui.control.DropDown
        AddPositivityConstraintCheckBox  matlab.ui.control.CheckBox
        FMPanel_CostFunction_2        matlab.ui.container.Panel
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Optimization                  matlab.ui.container.Tab
        FMPanel_CostFunction_3        matlab.ui.container.Panel
        Button_Add_Regul_2            matlab.ui.control.Button
        Button_SeeDoc_Regul_2         matlab.ui.control.Button
        AlgorithmLabel                matlab.ui.control.Label
        DropDown_Select_Regul_2       matlab.ui.control.DropDown
        Button_4                      matlab.ui.control.Button
        RUNButton                     matlab.ui.control.Button
        ExtractscrictButton           matlab.ui.control.Button
        RECAPButton                   matlab.ui.control.Button
        DisplaytheevolutionofthecostfunctionCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = public)

        % Components programatically added  
        LinOpCell={}   % Cell array containing the LinOps (ForwardModel panel)
                       % Each element of this cell contains a structure with fields
                       % - name     (name of the LinOp)
                       % - label    (Text with LinOp name in the zone where selected LinOps are displayed)
                       % - dlabel   (Text with LinOp notation -- e.g H1 -- in the zone where selected LinOps are displayed)
                       % - butDel   (delete button)
                       % - butUp    (up button)
                       % - butDown  (down button)
                       % - notation (letter used to represent the LinOp)
                       % - call     (command line used to instanciate the LinOp) 
                       % - parameters (cell with fields: name/type/val)
                       % - SetPropGUI (contains infos for displaying the GUI Set Parameters window)
                       
        CostCell={}    % Cell array containing the DataFid & Regul(CostFunction panel)
        
        logwd=[]       % Log window  
    end
    
    properties (Access = private)
      
        % Global properties
        doc_weblink = 'biomedical-imaging-group.github.io/GlobalBioIm/'; % Link to online documentation 
        
        % Properties for ForwardModel panel  
        maxLinOp         = 6;      % maximum number of concatenable linOps
        linOpText        = [];     % info displayed in text area for user 
        
        % Properties for CostFunction panel  
        maxRegul         = 3;      % maximum number of regularization terms 
        costText         = [];     % info displayed in text area for user 

        % Information for display 
        pos_left      = 50; 
        pos_bott_init = 325;
        incr_height   = 50;
        def_width     = 30; 
        def_height    = 20;
        
    end

    methods (Access = private)
        
        % ======================================
        %           COMMON FUNCTIONS 
        % ======================================
        
        % Open Log window
        function openLog(app,msg) 
            app.logwd.fig = uifigure;
            app.logwd.fig.Position =app.GlobalBioImGUIUIFigure.Position  +  [0 app.GlobalBioImGUIUIFigure.Position(4)/3 0 -app.GlobalBioImGUIUIFigure.Position(4)*2/3];
            app.logwd.fig.Name  = 'Log';
            app.logwd.TextArea = uitextarea(app.logwd.fig);     
            app.logwd.TextArea.Value = msg;
            app.logwd.TextArea.FontColor='red';
            app.logwd.TextArea.Position = [10 10 app.logwd.fig.Position(3)-20 app.logwd.fig.Position(4)-20];
        end
        
        % ======================================
        %           MAIN PANEL 
        % ======================================
        
        % ======================================
        %           FORWARD MODEL PANEL 
        % ======================================
        
        % Set list of linOps in dropdown menu
        function ListOfLinOpsStr = getStringListOfAvailableLinOps(app)
            dirList1 = struct2cell(dir('../LinOp/LinOp*.m')); 
            dirList2 = struct2cell(dir('../LinOp/SelectorLinOps/LinOp*.m'));            
            dirList = [dirList1 dirList2]; 
            idx=[];
            for ii=1:length(dirList)
                if ~isempty(regexp(fileread(dirList{1,ii}),'GUI-Header','once'))
                    idx=[idx,ii];
                end
            end
            dirList=dirList(1,idx);
            ListOfLinOpsStr = strrep(strrep(dirList,'.m',''),'LinOp','');  
        end
        
        % Delete hierarchy of linOps
        function deleteHierarchyLinOps(app)
            % Number of LinOps
            nLinOps = length(app.LinOpCell);
            
            % Loop over all the selected LinOps
            for idx=1:nLinOps
                delete(app.LinOpCell{idx}.label);
                delete(app.LinOpCell{idx}.butProp);
                delete(app.LinOpCell{idx}.butDel);
                delete(app.LinOpCell{idx}.ddlabel);
                delete(app.LinOpCell{idx}.butUp); 
                delete(app.LinOpCell{idx}.butDown); 
            end
        end 
        
        % Create hierarchy of linOps
        function createHierarchyLinOps(app)
            
           % Number of LinOps
           nLinOps = length(app.LinOpCell);
          
           % Loop over all the selected LinOps
           app.linOpText = 'H = ';
           for idx=1:nLinOps
               % Position info
               pos_bott      = app.pos_bott_init-(idx-1)*app.incr_height;
               
               % Add linOp name info
               app.LinOpCell{idx}.label = uilabel(app.ForwardModel);
               app.LinOpCell{idx}.label.Text = ['LinOp' ,app.LinOpCell{idx}.name];
               app.LinOpCell{idx}.label.Position = [app.pos_left+40 pos_bott app.def_width*4 app.def_height];
               
               % Update text for concatenation of linOps
               app.linOpText = [app.linOpText app.LinOpCell{idx}.notation];
               
               % Create label of drop-down component
               app.LinOpCell{idx}.ddlabel = uilabel(app.ForwardModel);
               app.LinOpCell{idx}.ddlabel.Position = [app.pos_left pos_bott app.def_width*1.5 app.def_height];
               app.LinOpCell{idx}.ddlabel.FontWeight = 'bold';
               app.LinOpCell{idx}.ddlabel.FontSize = 16;
               app.LinOpCell{idx}.ddlabel.Text = [app.LinOpCell{idx}.notation, ' : '];
               
               % Create button for setting properties
               app.LinOpCell{idx}.butProp = uibutton(app.ForwardModel, 'push');
               app.LinOpCell{idx}.butProp.Text = 'Set Parameters';
               app.LinOpCell{idx}.butProp.FontWeight = 'bold';
               app.LinOpCell{idx}.butProp.Position = [app.pos_left+180 pos_bott app.def_width*3.2 app.def_height];
               app.LinOpCell{idx}.butProp.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_PropButtonPushed(app,idx), true);
               
               % Create delete button
               app.LinOpCell{idx}.butDel = uibutton(app.ForwardModel, 'push');
               app.LinOpCell{idx}.butDel.Text = '';
               app.LinOpCell{idx}.butDel.Icon = 'trashIcon.png';
               app.LinOpCell{idx}.butDel.Position = [app.pos_left+340 pos_bott app.def_width*0.8 app.def_height];
               app.LinOpCell{idx}.butDel.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_DelLinOpButtonPushed(app,idx), true);
               
               % Create up button
               app.LinOpCell{idx}.butUp = uibutton(app.ForwardModel, 'push');
               app.LinOpCell{idx}.butUp.Text = '';
               app.LinOpCell{idx}.butUp.Icon = 'upArrow.png';
               app.LinOpCell{idx}.butUp.Position = [app.pos_left+310 pos_bott app.def_width*0.8 app.def_height];
               app.LinOpCell{idx}.butUp.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_UpLinOpButtonPushed(app,idx), true);
                         
               % Create down button
               app.LinOpCell{idx}.butDown = uibutton(app.ForwardModel, 'push');
               app.LinOpCell{idx}.butDown.Text = '';
               app.LinOpCell{idx}.butDown.Icon = 'downArrow.png';
               app.LinOpCell{idx}.butDown.Position = [app.pos_left+285 pos_bott app.def_width*0.8 app.def_height];
               app.LinOpCell{idx}.butDown.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_DownLinOpButtonPushed(app,idx), true);
               
           end
           if nLinOps>0
               app.LinOpCell{1}.butUp.Enable = 'off';
               app.LinOpCell{nLinOps}.butDown.Enable = 'off';
           end
           
           % Update text for concatenation of linOps
           app.TextArea_ForwardModel.Value = {app.linOpText};

        end 
    
        % Button pushed function: Button_PropButtonPushed
        function Button_PropButtonPushed(app,idx)
            
            % disable button
            app.LinOpCell{idx}.butProp.Enable='off';
            app.Button_AddLinOp.Enable='off';
            for ii=1:length(app.LinOpCell)
                app.LinOpCell{ii}.butDel.Enable='off';
                app.LinOpCell{ii}.butUp.Enable='off';
                app.LinOpCell{ii}.butDown.Enable='off';
            end
            
            % Create Figure
            hh=app.incr_height*(length(app.LinOpCell{idx}.parameters)+1);
            app.LinOpCell{idx}.SetPropGUI.fig = uifigure;
            app.LinOpCell{idx}.SetPropGUI.fig.Position  = app.GlobalBioImGUIUIFigure.Position  +  [app.GlobalBioImGUIUIFigure.Position(3) app.GlobalBioImGUIUIFigure.Position(4)-hh 0 -app.GlobalBioImGUIUIFigure.Position(4)+hh];
            app.LinOpCell{idx}.SetPropGUI.fig.Name  = 'Set Parameters';
            
            % Create Name
            app.LinOpCell{idx}.SetPropGUI.title = uilabel(app.LinOpCell{idx}.SetPropGUI.fig);
            app.LinOpCell{idx}.SetPropGUI.title.FontSize = 16;
            app.LinOpCell{idx}.SetPropGUI.title.FontWeight = 'bold';
            app.LinOpCell{idx}.SetPropGUI.title.Position = [19 hh-45 428 38];
            app.LinOpCell{idx}.SetPropGUI.title.Text = app.LinOpCell{idx}.label.Text;          
            
            % Create Apply Changes button
            app.LinOpCell{idx}.SetPropGUI.butApplyChanges = uibutton(app.LinOpCell{idx}.SetPropGUI.fig, 'push');
            app.LinOpCell{idx}.SetPropGUI.butApplyChanges.Position = [350 hh-35 100 20];
            app.LinOpCell{idx}.SetPropGUI.butApplyChanges.Text = 'Apply Changes';
            app.LinOpCell{idx}.SetPropGUI.butApplyChanges.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_ApplyChangesLinOpPushed(app,idx), true);
            
            % Create See Doc  button
            app.LinOpCell{idx}.SetPropGUI.butSeeDoc = uibutton(app.LinOpCell{idx}.SetPropGUI.fig, 'push');
            app.LinOpCell{idx}.SetPropGUI.butSeeDoc.Position = [280 hh-35 50 20];
            app.LinOpCell{idx}.SetPropGUI.butSeeDoc.Text = 'Doc.';
            app.LinOpCell{idx}.SetPropGUI.butSeeDoc.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_SeeLinOpDocButtonPushed(app,idx), true);
                        
            % Display parameters selection
            for ii=1:length(app.LinOpCell{idx}.parameters)
                pos_bott      = hh-(ii+0.5)*app.incr_height;
                % Info Button_
                app.LinOpCell{idx}.SetPropGUI.param{ii}.info = uibutton(app.LinOpCell{idx}.SetPropGUI.fig, 'push');
                app.LinOpCell{idx}.SetPropGUI.param{ii}.info.Icon = 'infoIcon.png';
                app.LinOpCell{idx}.SetPropGUI.param{ii}.info.Tooltip = {app.LinOpCell{idx}.parameters{ii}.info};
                app.LinOpCell{idx}.SetPropGUI.param{ii}.info.Position = [30 pos_bott 20 app.def_height];
                app.LinOpCell{idx}.SetPropGUI.param{ii}.info.Text = '';
                typ=app.LinOpCell{idx}.parameters{ii}.type;
                switch  typ
                    case 'file'
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label = uilabel(app.LinOpCell{idx}.SetPropGUI.fig);
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label.HorizontalAlignment = 'left';
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label.Position = [60 pos_bott 80 app.def_height];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label.Text = [app.LinOpCell{idx}.parameters{ii}.name,' : '];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.TextArea = uitextarea(app.LinOpCell{idx}.SetPropGUI.fig);
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.TextArea.Position = [150 pos_bott 220 app.def_height];
                        text=app.LinOpCell{idx}.parameters{ii}.val;
                        if isempty(text), text='Select a file (.png,.jpg,.tif,.mat)'; end
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.TextArea.Value=text;
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.butBrowse = uibutton(app.LinOpCell{idx}.SetPropGUI.fig, 'push');
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.butBrowse.Text = 'Browse';
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.butBrowse.Position = [380 pos_bott 60 app.def_height];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.butBrowse.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_BrowseSetPropLinOpPushed(app,idx,ii), true);
                    case 'vecInt'
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label = uilabel(app.LinOpCell{idx}.SetPropGUI.fig);
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label.HorizontalAlignment = 'left';
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label.Position = [60 pos_bott 80 app.def_height];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.label.Text = [app.LinOpCell{idx}.parameters{ii}.name,' : '];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.openBracket = uilabel(app.LinOpCell{idx}.SetPropGUI.fig);
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.openBracket.Position = [140 pos_bott-5 10 app.def_height*1.8];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.openBracket.Text = '[';
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.openBracket.FontSize = 30;
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.closeBracket = uilabel(app.LinOpCell{idx}.SetPropGUI.fig);
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.closeBracket.Position = [232 pos_bott-5 10 app.def_height*1.8];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.closeBracket.Text = ']';
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.closeBracket.FontSize = 30;
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.EditField = uieditfield(app.LinOpCell{idx}.SetPropGUI.fig,'text');
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.EditField.Position = [150 pos_bott 80 app.def_height];
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.EditField.Value=  app.LinOpCell{idx}.parameters{ii}.val;
                        app.LinOpCell{idx}.SetPropGUI.param{ii}.EditField.ValueChangedFcn = createCallbackFcn(app, @(app,event) EditField_ValueSetPropLinOpChanged(app,idx,ii), true);
                end
                
            end
        end
    
        % Button pushed function: Button_SeeLinOpDocButtonPushed
        function Button_SeeLinOpDocButtonPushed(app, idx)
            web([app.doc_weblink 'linop.html#linop' lower(app.LinOpCell{idx}.name)], '-notoolbar');
        end
    
        % Button pushed function: Button_ApplyChangesLinOpPushed
        function Button_ApplyChangesLinOpPushed(app,idx)
            if (~isempty(app.logwd) && ishandle(app.logwd.fig)), close(app.logwd.fig); end            
            err=0;msg={};
            for ii=1:length(app.LinOpCell{idx}.parameters)
                typ=app.LinOpCell{idx}.parameters{ii}.type;               
                switch  typ
                    case 'vecInt'
                        tmp=arrayfun(@(x) [num2str(x),' '],str2num(app.LinOpCell{idx}.parameters{ii}.val),'UniformOutput',false);
                        tmp=horzcat(tmp{:}); tmp=tmp(1:end-1);
                        if ~isempty(app.LinOpCell{idx}.parameters{ii}.val) &&  (~strcmp(tmp,app.LinOpCell{idx}.parameters{ii}.val) || all(mod(str2num(app.LinOpCell{idx}.parameters{ii}.val),1)~=0))
                            err=1;
                            msg{length(msg)+1}=['Wrong entry for parameter ',app.LinOpCell{idx}.parameters{ii}.name];
                            msg{length(msg)+1}=' --> A sequence of integers separated by a space is required (e.g. 512 512)';
                            app.LinOpCell{idx}.SetPropGUI.param{ii}.label.FontColor='red';
                        end
                end
            end
            if err==0
                close(app.LinOpCell{idx}.SetPropGUI.fig);
                b=true;
                for ii=1:length(app.LinOpCell)
                    b = b & (isfield(app.LinOpCell{ii},'SetPropGUI') & ~ishandle(app.LinOpCell{ii}.SetPropGUI.fig));
                end
                if b
                    for ii=1:length(app.LinOpCell)
                        app.LinOpCell{ii}.butDel.Enable='on';
                        if ii~=1, app.LinOpCell{ii}.butUp.Enable='on';end
                        if ii~=length(app.LinOpCell), app.LinOpCell{ii}.butDown.Enable='on';end
                    end
                    app.Button_AddLinOp.Enable='on';
                end
                app.LinOpCell{idx}.butProp.Enable='on';
            else
                openLog(app,msg);
            end
        end
    
        % Value Changed function: EditField_ValueSetPropLinOpChanged
        function EditField_ValueSetPropLinOpChanged(app,idx,ii)
            app.LinOpCell{idx}.parameters{ii}.val=app.LinOpCell{idx}.SetPropGUI.param{ii}.EditField.Value;
        end
    
        % Button pushed function: Button_BrowseSetPropLinOpPushed
        function Button_BrowseSetPropLinOpPushed(app,idx,ii)
            [file,path]=uigetfile();
            if file~=0
                app.LinOpCell{idx}.SetPropGUI.param{ii}.TextArea.Value=[path,file];
                app.LinOpCell{idx}.parameters{ii}.val=[path,file];
            end
        end
        
        % Button pushed function: Button_DelLinOpButtonPushed
        function Button_DelLinOpButtonPushed(app,idx)
            
            % Delete previously displayed text in the hierarchy box
            if ~isempty(app.LinOpCell)
                deleteHierarchyLinOps(app);
            end
            
            % Add linOp name info
            tmp=app.LinOpCell;
            app.LinOpCell={};
            pos=1;
            for ii=1:length(tmp)
                if ii~=idx
                    app.LinOpCell{pos}=tmp{ii};
                    pos=pos+1;
                end
            end
            
            % Display updated text in the hierarchy box
            createHierarchyLinOps(app); 
            
            % Enable addLinOp button if max number of linOps not reached
            if length(app.LinOpCell)<app.maxLinOp
                app.Button_AddLinOp.Enable = 'on';
            end
        end
    
        % Button pushed function: Button_UpLinOpButtonPushed
        function Button_UpLinOpButtonPushed(app,idx)
            
            % Delete previously displayed text in the hierarchy box
            if ~isempty(app.LinOpCell)
                deleteHierarchyLinOps(app);
            end
            
            % Reorganize the LinOpCell
            tmp=app.LinOpCell{idx-1};
            app.LinOpCell{idx-1}=app.LinOpCell{idx};
            app.LinOpCell{idx}=tmp;
            
            % Display updated text in the hierarchy box
            createHierarchyLinOps(app); 
        end
    
        % Button pushed function: Button_DownLinOpButtonPushed
        function Button_DownLinOpButtonPushed(app,idx)
            
            % Delete previously displayed text in the hierarchy box
            if ~isempty(app.LinOpCell)
                deleteHierarchyLinOps(app);
            end
            
            % Reorganize the LinOpCell
            tmp=app.LinOpCell{idx+1};
            app.LinOpCell{idx+1}=app.LinOpCell{idx};
            app.LinOpCell{idx}=tmp;
            
            % Display updated text in the hierarchy box
            createHierarchyLinOps(app); 
        end
        
        % ======================================
        %           COST FUNCTION PANEL 
        % ======================================
       
       
        % Delete hierarchy of regularizations 
        % ---------------------------------------
        function deleteHierarchyRegul(app)
        
            % Minimum text before adding reg terms 
            app.costText = 'C(x) = DF(x,y)';
            
            % Loop over all the selected regularizations 
            % Note: 1st entry of CostCell is data-fidelity 
            for idx=2:length(app.CostCell)
                delete(app.CostCell{idx}.label);
                delete(app.CostCell{idx}.butDel);
                delete(app.CostCell{idx}.ddlabel);
            end
        end 
        
        % Create data-fidelity term 
        % ---------------------------------------
        function createDataFidelity(app)
            app.TextArea_CostFunction.Value = 'C(x) = DF(x,y)';
            
            % Position info
            pos_bott      = app.pos_bott_init+app.incr_height*0.85;
            % Add Regul name info (for the selection box below)
            app.CostCell{1}.label = uilabel(app.CostFunction);
            app.CostCell{1}.label.Text = [app.CostCell{1}.name, ' data-fidelity'];
            app.CostCell{1}.label.Position = [app.pos_left+40 pos_bott app.def_width*8 app.def_height];
            
            % Create delete button (for the selection box below)
            app.CostCell{1}.butDel = uibutton(app.CostFunction, 'push');
            app.CostCell{1}.butDel.Text = '';
            app.CostCell{1}.butDel.Icon = 'trashIcon.png';
            app.CostCell{1}.butDel.Position = [app.pos_left+340 pos_bott app.def_width*0.8 app.def_height];
            app.CostCell{1}.butDel.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_DelLinOpButtonPushed(app,1), true);
            
            % Create delete button
            app.CostCell{1}.butProp = uibutton(app.CostFunction, 'push');
            app.CostCell{1}.butProp.Text = 'Set Parameters';
            app.CostCell{1}.butProp.FontWeight = 'bold';
            app.CostCell{1}.butProp.Position = [app.pos_left+230 pos_bott app.def_width*3.2 app.def_height];
            app.CostCell{1}.butProp.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_PropButtonPushed(app,1), true);
            
            % Create label of drop-down component (for the selection box below)
            app.CostCell{1}.ddlabel = uilabel(app.CostFunction);
            app.CostCell{1}.ddlabel.Position = [app.pos_left pos_bott app.def_width*1.5 app.def_height];
            app.CostCell{1}.ddlabel.FontWeight = 'bold';
            app.CostCell{1}.ddlabel.FontSize = 16;
            app.CostCell{1}.ddlabel.Text = 'DF: ';
            
        end
        
        % Create hierarchy of regularizations 
        % ---------------------------------------
        function createHierarchyRegul(app)
            
           % Loop over all the selected regularizations 
           % Note: 1st entry of CostCell is data-fidelity 
           for idx=2:length(app.CostCell)
               
               % Position info
               pos_bott      = app.pos_bott_init-(idx)*app.incr_height;
               
               % Add Regul name info (for the selection box below)
               app.CostCell{idx}.label = uilabel(app.CostFunction);
               app.CostCell{idx}.label.Text = [app.CostCell{idx}.name, ' regularization'];
               app.CostCell{idx}.label.Position = [app.pos_left+40 pos_bott-20 app.def_width*8 app.def_height]; 
                
               % Create label of drop-down component (for the selection box below)
               app.CostCell{idx}.ddlabel = uilabel(app.CostFunction);
               app.CostCell{idx}.ddlabel.Position = [app.pos_left pos_bott-20 app.def_width*1.5 app.def_height];
               app.CostCell{idx}.ddlabel.FontWeight = 'bold';
               app.CostCell{idx}.ddlabel.FontSize = 16;
               app.CostCell{idx}.ddlabel.Text = ['R' char(8320+(idx-1)) ' : '];
               
               % Create delete button (for the selection box below)
               app.CostCell{idx}.butDel = uibutton(app.CostFunction, 'push');
               app.CostCell{idx}.butDel.Text = '';
               app.CostCell{idx}.butDel.Icon = 'trashIcon.png';
               app.CostCell{idx}.butDel.Position = [app.pos_left+340 pos_bott-20 app.def_width*0.8 app.def_height];
               app.CostCell{idx}.butDel.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_DelLinOpButtonPushed(app,idx), true);
          
               % Update text for concatenation of regs (for the text box above)
               app.costText = [app.costText ' + ' char(955) char(8320+(idx-1)) 'R' char(8320+(idx-1)) '(x)']; 
           end
           
           % Update text for concatenation of linOps (for the text box above)
           app.TextArea_CostFunction.Value = {app.costText};

        end 

        % ======================================
        %           OPTIMIZATION PANEL 
        % ======================================
    
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % GENERAL ---------------
            
            % MAIN PANEL --------------
            
            % FORWARD MODEL PANEL ------------------
            ListOfLinOpsStr = getStringListOfAvailableLinOps(app); 
            app.DropDown_SelectLinOp.Items = ListOfLinOpsStr; 
            
            % COST FUNCTION PANEL ------------------
            dfList           = {'Quadratic (L2)', 'Kullback-Leibler (KL)'}; % list of available data fidelity terms 
            regList          = {'Tikhonov', 'Total-Variation (TV)', 'Hessian-Schatten (HS)'}; % list of available regularization terms 
            app.DropDown_Select_DataFidelity.Items = dfList; 
            app.DropDown_Select_Regul.Items = regList;
            
            % don't let the user choose a DF before the regul. 
            app.Button_Add_Regul.Enable = 'off'; 
            app.Button_SeeDoc_Regul.Enable = 'off'; 
            app.DropDown_Select_Regul.Enable = 'off'; 
            app.AddPositivityConstraintCheckBox.Enable = 'off'; 
         
        end

        % Button pushed function: Button_AddLinOp
        function Button_AddLinOpButtonPushed(app, event)
    
        % Delete previously displayed text in the hierarchy box 
           if ~isempty(app.LinOpCell)
               deleteHierarchyLinOps(app); 
           end 
            
           % Add linOp name info
           idxLinOp=length(app.LinOpCell)+1;
           app.LinOpCell{idxLinOp}.name=app.DropDown_SelectLinOp.Value;
           
           % Get parameters from comments
           text=splitlines(fileread(['LinOp',app.LinOpCell{idxLinOp}.name,'.m']));
           splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUInotation'))},'-','split'); % Get notation
           app.LinOpCell{idxLinOp}.notation=splitxt{2};
           splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUIcall'))},'-','split'); % Get call line
           app.LinOpCell{idxLinOp}.call=splitxt{2};
           ll=text(~cellfun(@isempty, regexp(text, 'GUIparam'))); % Get parameters
           for ii=1:length(ll)
               splitxt=regexp(ll{ii},'-','split');
               app.LinOpCell{idxLinOp}.parameters{ii}.name=splitxt{2};
               app.LinOpCell{idxLinOp}.parameters{ii}.type=splitxt{3};
               if strcmp('[]',splitxt{4}), text=''; else, text=splitxt{4}; end
               app.LinOpCell{idxLinOp}.parameters{ii}.val=text;
               app.LinOpCell{idxLinOp}.parameters{ii}.info=splitxt{5};
           end
           
           % Display updated text in the hierarchy box 
           createHierarchyLinOps(app); 
           
           % Disable addLinOp button if max number of linOps reached
           if length(app.LinOpCell)>=app.maxLinOp 
              app.Button_AddLinOp.Enable = 'off'; 
           end
           
        end

        % Button pushed function: Button_SeeDoc
        function Button_SeeDocButtonPushed(app, event)
           linOpName = app.DropDown_SelectLinOp.Value;
           web([app.doc_weblink 'linop.html#linop' lower(linOpName)], '-notoolbar'); 
        end

        % Button pushed function: Button_SeeDoc_DataFidelity
        function Button_SeeDoc_DataFidelityPushed(app, event)
            dfName = app.DropDown_Select_DataFidelity.Value;
           web([app.doc_weblink 'cost.html#cost' lower(dfName)], '-notoolbar'); 
        end

        % Button pushed function: Button_SeeDoc_Regul
        function Button_SeeDoc_RegulPushed(app, event)
           regName = app.DropDown_Select_Regul.Value;
           web([app.doc_weblink 'cost.html#cost' lower(regName)], '-notoolbar'); 
        end

        % Button pushed function: Button_Add_DataFidelity
        function Button_Add_DataFidelityPushed(app, event)
           % Add regul name info
           app.CostCell{1}.name=app.DropDown_Select_DataFidelity.Value;   
        
           % Display updated text in the hierarchy box 
           createDataFidelity(app); 
           
           % Allow user to select regul 
           app.Button_Add_Regul.Enable = 'on';
           app.Button_SeeDoc_Regul.Enable = 'on'; 
           app.DropDown_Select_Regul.Enable = 'on'; 
           app.AddPositivityConstraintCheckBox.Enable = 'on'; 
           app.Button_Add_DataFidelity.Enable = 'off';
           app.DropDown_Select_DataFidelity.Enable = 'off';
           app.Button_SeeDoc_DataFidelity.Enable = 'off';

        end

        % Button pushed function: Button_Add_Regul
        function Button_Add_RegulPushed(app, event)
           % Delete previously displayed text in the hierarchy box 
           if ~isempty(app.CostCell)
               deleteHierarchyRegul(app); 
           end 
            
           % Add linOp name info
           app.CostCell{length(app.CostCell)+1}.name=app.DropDown_Select_Regul.Value;          
           
           % Display updated text in the hierarchy box 
           createHierarchyRegul(app); 
           
           % Disable addLinOp button if max number of linOps reached
           if (length(app.CostCell)-1)>=app.maxRegul 
              app.Button_Add_Regul.Enable = 'off'; 
           end
          
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GlobalBioImGUIUIFigure = uifigure;
            app.GlobalBioImGUIUIFigure.Name = 'GlobalBioIm - GUI';
            app.TabGroup = uitabgroup(app.GlobalBioImGUIUIFigure);
            app.Main = uitab(app.TabGroup);
            app.Main.Title = 'Main';
            app.ORLabel = uilabel(app.Main);
            app.ORLabel.Text = 'OR';
            app.DesignandsolveyourowninverseproblemButton = uibutton(app.Main, 'push');
            app.DesignandsolveyourowninverseproblemButton.Text = 'Design and solve your own inverse problem';
            app.SelectandsolveanexistinginverseproblemButton = uibutton(app.Main, 'push');
            app.SelectandsolveanexistinginverseproblemButton.Text = 'Select and solve an existing inverse problem';
            app.LOADYOURDATALabel = uilabel(app.Main);
            app.LOADYOURDATALabel.Text = 'LOAD YOUR DATA';
            app.BrowseButton_2 = uibutton(app.Main, 'push');
            app.BrowseButton_2.Text = 'Browse';
            app.Button_6 = uibutton(app.Main, 'push');
            app.Button_6.Icon = 'infoIcon.png';
            app.Button_6.Text = '';
            app.ForwardModel = uitab(app.TabGroup);
            app.ForwardModel.Title = 'Forward Model';
            app.TextArea_ForwardModel = uitextarea(app.ForwardModel);
            app.LabelTextArea = uilabel(app.ForwardModel);
            app.LabelTextArea.Text = 'Forward Model';
            app.Button_AddLinOp = uibutton(app.ForwardModel, 'push');
            app.Button_AddLinOp.ButtonPushedFcn = createCallbackFcn(app, @Button_AddLinOpButtonPushed, true);
            app.Button_AddLinOp.Text = 'Select';
            app.Button_SeeDoc = uibutton(app.ForwardModel, 'push');
            app.Button_SeeDoc.ButtonPushedFcn = createCallbackFcn(app, @Button_SeeDocButtonPushed, true);
            app.Button_SeeDoc.Text = 'Doc.';
            app.OperatorLabel = uilabel(app.ForwardModel);
            app.OperatorLabel.Text = 'Operator';
            app.DropDown_SelectLinOp = uidropdown(app.ForwardModel);
            app.DropDown_SelectLinOp.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDown_SelectLinOp.Value = 'Test';
            app.Next = uibutton(app.ForwardModel, 'push');
            app.Next.Text = 'Check Your Forward Model (Recommended)';
            app.FMPanel = uipanel(app.ForwardModel);
            app.FMPanel.Title = 'Hierarchy of Selected Operators';
            app.Button_3 = uibutton(app.ForwardModel, 'push');
            app.Button_3.Icon = 'infoIcon.png';
            app.Button_3.Text = '';
            app.CostFunction = uitab(app.TabGroup);
            app.CostFunction.Title = 'Cost Function';
            app.TextArea_CostFunction = uitextarea(app.CostFunction);
            app.CostFunctionLabel = uilabel(app.CostFunction);
            app.CostFunctionLabel.Text = 'Cost Function';
            app.Button_Add_DataFidelity = uibutton(app.CostFunction, 'push');
            app.Button_Add_DataFidelity.ButtonPushedFcn = createCallbackFcn(app, @Button_Add_DataFidelityPushed, true);
            app.Button_Add_DataFidelity.Text = 'Select';
            app.Button_SeeDoc_DataFidelity = uibutton(app.CostFunction, 'push');
            app.Button_SeeDoc_DataFidelity.ButtonPushedFcn = createCallbackFcn(app, @Button_SeeDoc_DataFidelityPushed, true);
            app.Button_SeeDoc_DataFidelity.Text = 'Doc.';
            app.DatafidelityLabel = uilabel(app.CostFunction);
            app.DatafidelityLabel.Text = 'Data-fidelity';
            app.DropDown_Select_DataFidelity = uidropdown(app.CostFunction);
            app.DropDown_Select_DataFidelity.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDown_Select_DataFidelity.Value = 'Test';
            app.FMPanel_CostFunction = uipanel(app.CostFunction);
            app.FMPanel_CostFunction.Title = 'Selected Regularization Terms';
            app.Button_Add_Regul = uibutton(app.CostFunction, 'push');
            app.Button_Add_Regul.ButtonPushedFcn = createCallbackFcn(app, @Button_Add_RegulPushed, true);
            app.Button_Add_Regul.Text = 'Select';
            app.Button_SeeDoc_Regul = uibutton(app.CostFunction, 'push');
            app.Button_SeeDoc_Regul.ButtonPushedFcn = createCallbackFcn(app, @Button_SeeDoc_RegulPushed, true);
            app.Button_SeeDoc_Regul.Text = 'Doc.';
            app.RegularizerLabel = uilabel(app.CostFunction);
            app.RegularizerLabel.Text = 'Regularizer';
            app.DropDown_Select_Regul = uidropdown(app.CostFunction);
            app.DropDown_Select_Regul.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDown_Select_Regul.Value = 'Test';
            app.AddPositivityConstraintCheckBox = uicheckbox(app.CostFunction);
            app.AddPositivityConstraintCheckBox.Text = ' Add Positivity Constraint';
            app.FMPanel_CostFunction_2 = uipanel(app.CostFunction);
            app.FMPanel_CostFunction_2.Title = 'Selected Data-Fidelity Term';
            app.Button = uibutton(app.CostFunction, 'push');
            app.Button.Icon = 'infoIcon.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.CostFunction, 'push');
            app.Button_2.Icon = 'infoIcon.png';
            app.Button_2.Text = '';
            app.Optimization = uitab(app.TabGroup);
            app.Optimization.Title = 'Optimization';
            app.FMPanel_CostFunction_3 = uipanel(app.Optimization);
            app.FMPanel_CostFunction_3.Title = 'Selected Algorithm';
            app.Button_Add_Regul_2 = uibutton(app.Optimization, 'push');
            app.Button_Add_Regul_2.Text = 'Select';
            app.Button_SeeDoc_Regul_2 = uibutton(app.Optimization, 'push');
            app.Button_SeeDoc_Regul_2.Text = 'Doc.';
            app.AlgorithmLabel = uilabel(app.Optimization);
            app.AlgorithmLabel.Text = 'Algorithm';
            app.DropDown_Select_Regul_2 = uidropdown(app.Optimization);
            app.DropDown_Select_Regul_2.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDown_Select_Regul_2.Value = 'Test';
            app.Button_4 = uibutton(app.Optimization, 'push');
            app.Button_4.Icon = 'infoIcon.png';
            app.Button_4.Text = '';
            app.RUNButton = uibutton(app.Optimization, 'push');
            app.RUNButton.Text = 'RUN';
            app.ExtractscrictButton = uibutton(app.Optimization, 'push');
            app.ExtractscrictButton.Text = 'Extract scrict';
            app.RECAPButton = uibutton(app.Optimization, 'push');
            app.RECAPButton.Text = 'RECAP';
            app.DisplaytheevolutionofthecostfunctionCheckBox = uicheckbox(app.Optimization);
            app.DisplaytheevolutionofthecostfunctionCheckBox.Text = ' Display the evolution of the cost function';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_GlobalBioIm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GlobalBioImGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GlobalBioImGUIUIFigure)
        end
    end
end